classdef simple_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        xButton                         matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        Button_12                       matlab.ui.control.Button
        Button_13                       matlab.ui.control.Button
        Button_14                       matlab.ui.control.Button
        Button_15                       matlab.ui.control.Button
        DELButton                       matlab.ui.control.Button
        MinhKhoissimplecalculatorLabel  matlab.ui.control.Label
        ResultEditFieldLabel            matlab.ui.control.Label
        ResultEditField                 matlab.ui.control.NumericEditField
        Button_16                       matlab.ui.control.Button
        Button_17                       matlab.ui.control.Button
        Button_18                       matlab.ui.control.Button
        expButton                       matlab.ui.control.Button
        xButton_2                       matlab.ui.control.Button
        SOLVEButton                     matlab.ui.control.Button
        Button_19                       matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        DispxintegralandderivativeEditFieldLabel  matlab.ui.control.Label
        DispxintegralandderivativeEditField  matlab.ui.control.EditField
        PolynomialeqnDropDownLabel      matlab.ui.control.Label
        PolynomialeqnDropDown           matlab.ui.control.DropDown
        SolveequationCheckBox           matlab.ui.control.CheckBox
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        cEditField                      matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        dEditField                      matlab.ui.control.NumericEditField
        eEditFieldLabel                 matlab.ui.control.Label
        eEditField                      matlab.ui.control.NumericEditField
        cosButton                       matlab.ui.control.Button
        sinButton                       matlab.ui.control.Button
        tanButton                       matlab.ui.control.Button
        DerivativeButton                matlab.ui.control.Button
        IntegralButton                  matlab.ui.control.Button
        log10Button                     matlab.ui.control.Button
        naturallogButton                matlab.ui.control.Button
        logxButton                      matlab.ui.control.Button
        sqrtxButton                     matlab.ui.control.Button
        Button_20                       matlab.ui.control.Button
    end


    properties (Access = private)
        memory = [];
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.memory = [app.memory '7'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.memory = [app.memory '8'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.memory = [app.memory '9'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.memory = [app.memory '4'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.memory = [app.memory '5'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.memory = [app.memory '6'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.memory = [app.memory '1'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.memory = [app.memory '2'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.memory = [app.memory '3'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.memory = [app.memory '0'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.memory = [app.memory '+'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            app.memory = [app.memory '*'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.memory = [app.memory '-'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.memory = [app.memory '/'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            %checkpoint = isnumeric(str2num(app.memory));
            %if checkpoint == 1
                app.ResultEditField.Value = str2num(app.memory);
            %else
                %app.EditField.Value = 'error';
                %app.ResultEditField.Value = 0;
            %end
        end

        % Button pushed function: DELButton
        function DELButtonPushed(app, event)
            app.memory = '';
            app.EditField.Value = app.memory;
            app.ResultEditField.Value = 0;
            app.DispxintegralandderivativeEditField.Value = '';
            app.aEditField.Value = 0;
            app.bEditField.Value = 0;
            app.cEditField.Value = 0;
            app.dEditField.Value = 0;
            app.eEditField.Value = 0;
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.memory = [app.memory '.'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            app.memory = [app.memory '('];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            app.memory = [app.memory ')'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: expButton
        function expButtonPushed(app, event)
            app.memory = [app.memory '^'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: xButton_2
        function xButton_2Pushed(app, event)
            app.memory = [app.memory 'x'];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            app.memory = [app.memory '=='];
            app.EditField.Value = app.memory;
        end

        % Value changed function: PolynomialeqnDropDown
        function PolynomialeqnDropDownValueChanged(app, event)
            value = app.PolynomialeqnDropDown.Value;
            switch value
                case 'Quadratic eqn'
                    %app.DispxintegralandderivativeEditField.Value = a = num2str(input('nhap he so a : '));
                    app.EditField.Value = 'a*x^4 + b*x^3 + c*x^2 + d*x + e == 0';
                    app.aEditField.Enable = 'off';
                    app.bEditField.Enable = 'off';
                    app.cEditField.Enable = 'on';
                    app.dEditField.Enable = 'on';
                    app.eEditField.Enable = 'on';
                case 'Cubic eqn'
                    app.EditField.Value = 'a*x^4 + b*x^3 + c*x^2 + d*x + e == 0';
                    app.aEditField.Enable = 'off';
                    app.bEditField.Enable = 'on';
                    app.cEditField.Enable = 'on';
                    app.dEditField.Enable = 'on';
                    app.eEditField.Enable = 'on';
                case 'Quartic eqn'
                    app.EditField.Value = 'a*x^4 + b*x^3 + c*x^2 + d*x + e == 0';
                    app.aEditField.Enable = 'on';
                    app.bEditField.Enable = 'on';
                    app.cEditField.Enable = 'on';
                    app.dEditField.Enable = 'on';
                    app.eEditField.Enable = 'on';
                otherwise
                    app.EditField.Value = app.memory;
                    app.aEditField.Enable = 'off';
                    app.bEditField.Enable = 'off';
                    app.cEditField.Enable = 'off';
                    app.dEditField.Enable = 'off';
                    app.eEditField.Enable = 'off';
            end
        end

        % Value changed function: SolveequationCheckBox
        function SolveequationCheckBoxValueChanged(app, event)
            value = app.SolveequationCheckBox.Value;
            if (value == 1)
                app.PolynomialeqnDropDown.Enable = 'on';
                app.SOLVEButton.Enable = 'on';
                app.aEditField.Enable = 'off';
                app.bEditField.Enable = 'off';
                app.cEditField.Enable = 'off';
                app.dEditField.Enable = 'off';
                app.eEditField.Enable = 'off';
            else
                app.PolynomialeqnDropDown.Enable = 'off';
                app.SOLVEButton.Enable = 'off';
                app.aEditField.Enable = 'off';
                app.bEditField.Enable = 'off';
                app.cEditField.Enable = 'off';
                app.dEditField.Enable = 'off';
                app.eEditField.Enable = 'off';
            end
        end

        % Button pushed function: SOLVEButton
        function SOLVEButtonPushed(app, event)
            switch app.PolynomialeqnDropDown.Value
                case 'Quadratic eqn'
                    syms x c d e
                    transition_step_1 = subs(c*x^2 + d*x + e == 0, [c, d, e], [app.cEditField.Value, app.dEditField.Value, app.eEditField.Value]);
                    transition_step_2 = double(solve(transition_step_1));
                    transition_step_3 = reshape(transition_step_2, 1, 2);
                    transition_step_4 = num2str(transition_step_3);
                    app.DispxintegralandderivativeEditField.Value = transition_step_4;
                case 'Cubic eqn'
                    syms x b c d e
                    transition_step_1 = subs(b*x^3 + c*x^2 + d*x + e == 0, [b, c, d, e], [app.bEditField.Value, app.cEditField.Value, app.dEditField.Value, app.eEditField.Value]);
                    transition_step_2 = double(solve(transition_step_1));
                    transition_step_3 = reshape(transition_step_2, 1, 3);
                    transition_step_4 = num2str(transition_step_3);
                    app.DispxintegralandderivativeEditField.Value = transition_step_4;
                case 'Quartic eqn'
                    syms x a b c d e
                    transition_step_1 = subs(a*x^4 + b*x^3 + c*x^2 + d*x + e == 0, [a, b, c, d, e], [app.aEditField.Value, app.bEditField.Value, app.cEditField.Value, app.dEditField.Value, app.eEditField.Value]);
                    transition_step_2 = double(solve(transition_step_1));
                    transition_step_3 = reshape(transition_step_2, 1, 4);
                    transition_step_4 = num2str(transition_step_3);
                    app.DispxintegralandderivativeEditField.Value = transition_step_4;
                otherwise
                    syms x
                    transition_step_1 = str2sym(app.memory);
                    transition_step_2 = double(solve(transition_step_1));
                    transition_step_3 = reshape(transition_step_2, 1, 1);
                    transition_step_4 = num2str(transition_step_3);
                    app.DispxintegralandderivativeEditField.Value = transition_step_4;
            end
        end

        % Button pushed function: cosButton
        function cosButtonPushed(app, event)
            app.memory = [app.memory 'cosd('];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: sinButton
        function sinButtonPushed(app, event)
            app.memory = [app.memory 'sind('];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: tanButton
        function tanButtonPushed(app, event)
            app.memory = [app.memory 'tand('];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: DerivativeButton
        function DerivativeButtonPushed(app, event)
            syms x
            transition_step_1 = str2sym(app.memory);
            transition_step_2 = diff(transition_step_1);
            transition_step_3 = string(transition_step_2);
            app.DispxintegralandderivativeEditField.Value = transition_step_3;
        end

        % Button pushed function: IntegralButton
        function IntegralButtonPushed(app, event)
            syms x
            transition_step_1 = str2sym(app.memory);
            transition_step_2 = int(transition_step_1);
            transition_step_3 = string(transition_step_2);
            app.DispxintegralandderivativeEditField.Value = transition_step_3;
        end

        % Button pushed function: naturallogButton
        function naturallogButtonPushed(app, event)
            app.memory = [app.memory 'log('];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: log10Button
        function log10ButtonPushed(app, event)
            app.memory = [app.memory 'log10('];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: sqrtxButton
        function sqrtxButtonPushed(app, event)
            app.memory = [app.memory 'sqrt('];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: logxButton
        function logxButtonPushed(app, event)
            app.memory = [app.memory 'log_a('];
            app.EditField.Value = app.memory;
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            app.memory = [app.memory ','];
            app.EditField.Value = app.memory;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '7';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '8';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '9';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '4';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '6';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '1';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '2';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '3';
            app.xButton = uibutton(app.UIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '+';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '0';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = ':';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '-';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '=';
            app.DELButton = uibutton(app.UIFigure, 'push');
            app.DELButton.ButtonPushedFcn = createCallbackFcn(app, @DELButtonPushed, true);
            app.DELButton.Text = 'DEL';
            app.MinhKhoissimplecalculatorLabel = uilabel(app.UIFigure);
            app.MinhKhoissimplecalculatorLabel.Text = 'Minh Khoi''s simple calculator';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultEditField.Editable = 'off';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '.';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '(';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = ')';
            app.expButton = uibutton(app.UIFigure, 'push');
            app.expButton.ButtonPushedFcn = createCallbackFcn(app, @expButtonPushed, true);
            app.expButton.Text = 'exp';
            app.xButton_2 = uibutton(app.UIFigure, 'push');
            app.xButton_2.ButtonPushedFcn = createCallbackFcn(app, @xButton_2Pushed, true);
            app.xButton_2.Text = 'x';
            app.SOLVEButton = uibutton(app.UIFigure, 'push');
            app.SOLVEButton.ButtonPushedFcn = createCallbackFcn(app, @SOLVEButtonPushed, true);
            app.SOLVEButton.Enable = 'off';
            app.SOLVEButton.Text = 'SOLVE';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '==';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.DispxintegralandderivativeEditFieldLabel = uilabel(app.UIFigure);
            app.DispxintegralandderivativeEditFieldLabel.Text = 'Disp x, integral and derivative';
            app.DispxintegralandderivativeEditField = uieditfield(app.UIFigure, 'text');
            app.DispxintegralandderivativeEditField.Editable = 'off';
            app.PolynomialeqnDropDownLabel = uilabel(app.UIFigure);
            app.PolynomialeqnDropDownLabel.Enable = 'off';
            app.PolynomialeqnDropDownLabel.Text = 'Polynomial eqn';
            app.PolynomialeqnDropDown = uidropdown(app.UIFigure);
            app.PolynomialeqnDropDown.Items = {'Quadratic eqn', 'Cubic eqn', 'Quartic eqn', 'Other function'};
            app.PolynomialeqnDropDown.ValueChangedFcn = createCallbackFcn(app, @PolynomialeqnDropDownValueChanged, true);
            app.PolynomialeqnDropDown.Enable = 'off';
            app.PolynomialeqnDropDown.Value = 'Quadratic eqn';
            app.SolveequationCheckBox = uicheckbox(app.UIFigure);
            app.SolveequationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SolveequationCheckBoxValueChanged, true);
            app.SolveequationCheckBox.Text = 'Solve equation?';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Enable = 'off';
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Enable = 'off';
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.Enable = 'off';
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.Enable = 'off';
            app.eEditFieldLabel = uilabel(app.UIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.UIFigure, 'numeric');
            app.eEditField.Enable = 'off';
            app.cosButton = uibutton(app.UIFigure, 'push');
            app.cosButton.ButtonPushedFcn = createCallbackFcn(app, @cosButtonPushed, true);
            app.cosButton.Text = 'cos';
            app.sinButton = uibutton(app.UIFigure, 'push');
            app.sinButton.ButtonPushedFcn = createCallbackFcn(app, @sinButtonPushed, true);
            app.sinButton.Text = 'sin';
            app.tanButton = uibutton(app.UIFigure, 'push');
            app.tanButton.ButtonPushedFcn = createCallbackFcn(app, @tanButtonPushed, true);
            app.tanButton.Text = 'tan';
            app.DerivativeButton = uibutton(app.UIFigure, 'push');
            app.DerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @DerivativeButtonPushed, true);
            app.DerivativeButton.Text = 'Derivative';
            app.IntegralButton = uibutton(app.UIFigure, 'push');
            app.IntegralButton.ButtonPushedFcn = createCallbackFcn(app, @IntegralButtonPushed, true);
            app.IntegralButton.Text = 'Integral';
            app.log10Button = uibutton(app.UIFigure, 'push');
            app.log10Button.ButtonPushedFcn = createCallbackFcn(app, @log10ButtonPushed, true);
            app.log10Button.Text = 'log10';
            app.naturallogButton = uibutton(app.UIFigure, 'push');
            app.naturallogButton.ButtonPushedFcn = createCallbackFcn(app, @naturallogButtonPushed, true);
            app.naturallogButton.Text = 'natural log';
            app.logxButton = uibutton(app.UIFigure, 'push');
            app.logxButton.ButtonPushedFcn = createCallbackFcn(app, @logxButtonPushed, true);
            app.logxButton.Text = 'logx()';
            app.sqrtxButton = uibutton(app.UIFigure, 'push');
            app.sqrtxButton.ButtonPushedFcn = createCallbackFcn(app, @sqrtxButtonPushed, true);
            app.sqrtxButton.Text = 'sqrt(x)';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = ',';
        end
    end

    methods (Access = public)

        % Construct app
        function app = simple_calculator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end